classdef SynthUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TimbreControls1Tab              matlab.ui.container.Tab
        CoarseFrequencyPanel            matlab.ui.container.Panel
        Osc1KnobLabel                   matlab.ui.control.Label
        Osc1Knob                        matlab.ui.control.DiscreteKnob
        Osc6KnobLabel                   matlab.ui.control.Label
        Osc6Knob                        matlab.ui.control.DiscreteKnob
        Osc5KnobLabel                   matlab.ui.control.Label
        Osc5Knob                        matlab.ui.control.DiscreteKnob
        Osc2KnobLabel                   matlab.ui.control.Label
        Osc2Knob                        matlab.ui.control.DiscreteKnob
        Osc3KnobLabel                   matlab.ui.control.Label
        Osc3Knob                        matlab.ui.control.DiscreteKnob
        Osc4KnobLabel                   matlab.ui.control.Label
        Osc4Knob                        matlab.ui.control.DiscreteKnob
        FineFrequencyPanel              matlab.ui.container.Panel
        Osc1Knob_2Label                 matlab.ui.control.Label
        Osc1Knob_2                      matlab.ui.control.Knob
        Osc2Knob_2Label                 matlab.ui.control.Label
        Osc2Knob_2                      matlab.ui.control.Knob
        Osc3Knob_2Label                 matlab.ui.control.Label
        Osc3Knob_2                      matlab.ui.control.Knob
        Osc4Knob_2Label                 matlab.ui.control.Label
        Osc4Knob_2                      matlab.ui.control.Knob
        Osc5Knob_2Label                 matlab.ui.control.Label
        Osc5Knob_2                      matlab.ui.control.Knob
        Osc6Knob_2Label                 matlab.ui.control.Label
        Osc6Knob_2                      matlab.ui.control.Knob
        ModEnvAmtPanel                  matlab.ui.container.Panel
        Osc1Knob_3Label                 matlab.ui.control.Label
        Osc1Knob_3                      matlab.ui.control.Knob
        Osc2Knob_3Label                 matlab.ui.control.Label
        Osc2Knob_3                      matlab.ui.control.Knob
        Osc3Knob_3Label                 matlab.ui.control.Label
        Osc3Knob_3                      matlab.ui.control.Knob
        Osc4Knob_3Label                 matlab.ui.control.Label
        Osc4Knob_3                      matlab.ui.control.Knob
        Osc5Knob_3Label                 matlab.ui.control.Label
        Osc5Knob_3                      matlab.ui.control.Knob
        Osc6Knob_3Label                 matlab.ui.control.Label
        Osc6Knob_3                      matlab.ui.control.Knob
        OutputLevelsPanel               matlab.ui.container.Panel
        Osc1SliderLabel                 matlab.ui.control.Label
        Osc1Slider                      matlab.ui.control.Slider
        Osc2SliderLabel                 matlab.ui.control.Label
        Osc2Slider                      matlab.ui.control.Slider
        Osc3SliderLabel                 matlab.ui.control.Label
        Osc3Slider                      matlab.ui.control.Slider
        Osc4SliderLabel                 matlab.ui.control.Label
        Osc4Slider                      matlab.ui.control.Slider
        Osc5SliderLabel                 matlab.ui.control.Label
        Osc5Slider                      matlab.ui.control.Slider
        Osc6SliderLabel                 matlab.ui.control.Label
        Osc6Slider                      matlab.ui.control.Slider
        LFOADepthPanel                  matlab.ui.container.Panel
        Osc1Knob_4Label                 matlab.ui.control.Label
        Osc1Knob_4                      matlab.ui.control.Knob
        Osc2Knob_4Label                 matlab.ui.control.Label
        Osc2Knob_4                      matlab.ui.control.Knob
        Osc3Knob_4Label                 matlab.ui.control.Label
        Osc3Knob_4                      matlab.ui.control.Knob
        Osc4Knob_4Label                 matlab.ui.control.Label
        Osc4Knob_4                      matlab.ui.control.Knob
        Osc5Knob_4Label                 matlab.ui.control.Label
        Osc5Knob_4                      matlab.ui.control.Knob
        Osc6Knob_4Label                 matlab.ui.control.Label
        Osc6Knob_4                      matlab.ui.control.Knob
        LFOBDepthPanel                  matlab.ui.container.Panel
        Osc1Knob_5Label                 matlab.ui.control.Label
        Osc1Knob_5                      matlab.ui.control.Knob
        Osc2Knob_5Label                 matlab.ui.control.Label
        Osc2Knob_5                      matlab.ui.control.Knob
        Osc3Knob_5Label                 matlab.ui.control.Label
        Osc3Knob_5                      matlab.ui.control.Knob
        Osc4Knob_5Label                 matlab.ui.control.Label
        Osc4Knob_5                      matlab.ui.control.Knob
        Osc5Knob_5Label                 matlab.ui.control.Label
        Osc5Knob_5                      matlab.ui.control.Knob
        Osc6Knob_5Label                 matlab.ui.control.Label
        Osc6Knob_5                      matlab.ui.control.Knob
        TimbreControls2Tab              matlab.ui.container.Tab
        PhaseModulationParametersPanel  matlab.ui.container.Panel
        Osc11KnobLabel                  matlab.ui.control.Label
        Osc11Knob                       matlab.ui.control.Knob
        Osc12KnobLabel                  matlab.ui.control.Label
        Osc12Knob                       matlab.ui.control.Knob
        Osc13KnobLabel                  matlab.ui.control.Label
        Osc13Knob                       matlab.ui.control.Knob
        Osc14KnobLabel                  matlab.ui.control.Label
        Osc14Knob                       matlab.ui.control.Knob
        Osc15KnobLabel                  matlab.ui.control.Label
        Osc15Knob                       matlab.ui.control.Knob
        Osc16KnobLabel                  matlab.ui.control.Label
        Osc16Knob                       matlab.ui.control.Knob
        Osc21KnobLabel                  matlab.ui.control.Label
        Osc21Knob                       matlab.ui.control.Knob
        Osc22KnobLabel                  matlab.ui.control.Label
        Osc22Knob                       matlab.ui.control.Knob
        Osc23KnobLabel                  matlab.ui.control.Label
        Osc23Knob                       matlab.ui.control.Knob
        Osc24KnobLabel                  matlab.ui.control.Label
        Osc24Knob                       matlab.ui.control.Knob
        Osc25KnobLabel                  matlab.ui.control.Label
        Osc25Knob                       matlab.ui.control.Knob
        Osc26KnobLabel                  matlab.ui.control.Label
        Osc26Knob                       matlab.ui.control.Knob
        Osc31KnobLabel                  matlab.ui.control.Label
        Osc31Knob                       matlab.ui.control.Knob
        Osc32KnobLabel                  matlab.ui.control.Label
        Osc32Knob                       matlab.ui.control.Knob
        Osc33KnobLabel                  matlab.ui.control.Label
        Osc33Knob                       matlab.ui.control.Knob
        Osc34KnobLabel                  matlab.ui.control.Label
        Osc34Knob                       matlab.ui.control.Knob
        Osc35KnobLabel                  matlab.ui.control.Label
        Osc35Knob                       matlab.ui.control.Knob
        Osc36KnobLabel                  matlab.ui.control.Label
        Osc36Knob                       matlab.ui.control.Knob
        Osc41KnobLabel                  matlab.ui.control.Label
        Osc41Knob                       matlab.ui.control.Knob
        Osc42KnobLabel                  matlab.ui.control.Label
        Osc42Knob                       matlab.ui.control.Knob
        Osc43KnobLabel                  matlab.ui.control.Label
        Osc43Knob                       matlab.ui.control.Knob
        Osc44KnobLabel                  matlab.ui.control.Label
        Osc44Knob                       matlab.ui.control.Knob
        Osc45KnobLabel                  matlab.ui.control.Label
        Osc45Knob                       matlab.ui.control.Knob
        Osc46KnobLabel                  matlab.ui.control.Label
        Osc46Knob                       matlab.ui.control.Knob
        Osc51KnobLabel                  matlab.ui.control.Label
        Osc51Knob                       matlab.ui.control.Knob
        Osc52KnobLabel                  matlab.ui.control.Label
        Osc52Knob                       matlab.ui.control.Knob
        Osc53KnobLabel                  matlab.ui.control.Label
        Osc53Knob                       matlab.ui.control.Knob
        Osc54KnobLabel                  matlab.ui.control.Label
        Osc54Knob                       matlab.ui.control.Knob
        Osc55KnobLabel                  matlab.ui.control.Label
        Osc55Knob                       matlab.ui.control.Knob
        Osc56KnobLabel                  matlab.ui.control.Label
        Osc56Knob                       matlab.ui.control.Knob
        Osc61KnobLabel                  matlab.ui.control.Label
        Osc61Knob                       matlab.ui.control.Knob
        Osc62KnobLabel                  matlab.ui.control.Label
        Osc62Knob                       matlab.ui.control.Knob
        Osc63KnobLabel                  matlab.ui.control.Label
        Osc63Knob                       matlab.ui.control.Knob
        Osc64KnobLabel                  matlab.ui.control.Label
        Osc64Knob                       matlab.ui.control.Knob
        Osc65KnobLabel                  matlab.ui.control.Label
        Osc65Knob                       matlab.ui.control.Knob
        Osc66KnobLabel                  matlab.ui.control.Label
        Osc66Knob                       matlab.ui.control.Knob
        TimeControlsTab                 matlab.ui.container.Tab
        LFOAPanel                       matlab.ui.container.Panel
        LfoAAxes                        matlab.ui.control.UIAxes
        RateHzKnob_3Label               matlab.ui.control.Label
        LfoARateKnob                    matlab.ui.control.Knob
        AmpKnob_3Label                  matlab.ui.control.Label
        LfoAAmpKnob                     matlab.ui.control.Knob
        PhaseSpreadKnob_2Label          matlab.ui.control.Label
        LfoAPhaseSpreadKnob             matlab.ui.control.Knob
        LFOBPanel                       matlab.ui.container.Panel
        LfoBAxes                        matlab.ui.control.UIAxes
        RateHzKnob_4Label               matlab.ui.control.Label
        LfoBRateKnob                    matlab.ui.control.Knob
        AmpKnob_4Label                  matlab.ui.control.Label
        LfoBAmpKnob                     matlab.ui.control.Knob
        PulseWidthKnob_2Label           matlab.ui.control.Label
        LfoBPulseWidthKnob              matlab.ui.control.Knob
        EnvelopesPanel                  matlab.ui.container.Panel
        EnvelopesAxes                   matlab.ui.control.UIAxes
        ModulationEnvelopePanel         matlab.ui.container.Panel
        ASlider_3Label                  matlab.ui.control.Label
        ModASlider                      matlab.ui.control.Slider
        DSlider_3Label                  matlab.ui.control.Label
        ModDSlider                      matlab.ui.control.Slider
        SSlider_3Label                  matlab.ui.control.Label
        ModSSlider                      matlab.ui.control.Slider
        RSlider_3Label                  matlab.ui.control.Label
        ModRSlider                      matlab.ui.control.Slider
        CurveKnob_3Label                matlab.ui.control.Label
        ModCurveKnob                    matlab.ui.control.Knob
        AmplitudeEnvelopePanel          matlab.ui.container.Panel
        ASlider_4Label                  matlab.ui.control.Label
        AmpASlider                      matlab.ui.control.Slider
        DSlider_4Label                  matlab.ui.control.Label
        AmpDSlider                      matlab.ui.control.Slider
        SSlider_4Label                  matlab.ui.control.Label
        AmpSSlider                      matlab.ui.control.Slider
        RSlider_4Label                  matlab.ui.control.Label
        AmpRSlider                      matlab.ui.control.Slider
        CurveKnob_4Label                matlab.ui.control.Label
        AmpCurveKnob                    matlab.ui.control.Knob
        VibratoPanel                    matlab.ui.container.Panel
        StartRateHzKnob_2Label          matlab.ui.control.Label
        VibStartRateKnob                matlab.ui.control.Knob
        EndRateHzKnob_2Label            matlab.ui.control.Label
        VibEndRateKnob                  matlab.ui.control.Knob
        StartAmtKnob_2Label             matlab.ui.control.Label
        VibStartAmtKnob                 matlab.ui.control.Knob
        EndAmtKnob_2Label               matlab.ui.control.Label
        VibEndAmtKnob                   matlab.ui.control.Knob
        EnvTimesKnob_2Label             matlab.ui.control.Label
        VibEnvTimeKnob                  matlab.ui.control.Knob
        GlobalModifiersPanel            matlab.ui.container.Panel
        StereoSpreadKnob_2Label         matlab.ui.control.Label
        StereoSpreadKnob                matlab.ui.control.Knob
        LoadSelectedPresetButton        matlab.ui.control.Button
        PresetSpinnerLabel              matlab.ui.control.Label
        PresetSpinner                   matlab.ui.control.Spinner
        ReWriteSelectedPresetButton     matlab.ui.control.Button
        SaveasNewPresetButton           matlab.ui.control.Button
        ReturntoSelectionUIButton       matlab.ui.control.Button
    end


    properties (Access = private)
        
    end

    properties (Access = public)
        UDP   % UDP connection
        
        presetRead
        presetSave
        
        selectedPreset
        
        coarseFrequencies
        fineFrequencies
        modEnvAmt
        lfoADepth
        lfoBDepth
        outputLevels
        
        PMparams
        
        lfoAParams
        lfoBParams
        envAmpParams
        env1Params
        misc
        
        lfoAPlot
        lfoBPlot
        modEnvPlot
        ampEnvPlot
        
        
        pcaAppData
    end
    methods (Access = public)
        
        function app = loadPreset(app, preset)
            
            app.PMparams = preset{1};
            app.coarseFrequencies = preset{2};
            app.fineFrequencies = max(0, preset{3});
            app.outputLevels = preset{4};
            app.modEnvAmt = preset{5};
            app.lfoADepth = preset{6};
            app.lfoBDepth = preset{7};
            app.lfoAParams = max(0, preset{8});
            app.lfoBParams = max(0, preset{9});
            app.envAmpParams = [max(0, preset{10}(1:4)), preset{10}(5)];
            app.env1Params = [max(0, preset{11}(1:4)), preset{11}(5)];
            app.misc = max(0.001, preset{12});
            
            updateAllKnobs(app);
            sendAllParametersOverOSC(app);
            
            updateLfoAPlot(app);
            updateLfoBPlot(app);
            updateAmpEnvPlot(app);
            updateModEnvPlot(app);
        end 
    end
    methods (Access = private)
    
        function app = setCoarseFrequencyKnobs(app)
            app.Osc1Knob.Value = num2str(bound(mapToFreqCoarse(app.coarseFrequencies(1)), 0.5, 10));
            app.Osc2Knob.Value = num2str(bound(mapToFreqCoarse(app.coarseFrequencies(2)), 0.5, 10));
            app.Osc3Knob.Value = num2str(bound(mapToFreqCoarse(app.coarseFrequencies(3)), 0.5, 10));
            app.Osc4Knob.Value = num2str(bound(mapToFreqCoarse(app.coarseFrequencies(4)), 0.5, 10));
            app.Osc5Knob.Value = num2str(bound(mapToFreqCoarse(app.coarseFrequencies(5)), 0.5, 10));
            app.Osc6Knob.Value = num2str(bound(mapToFreqCoarse(app.coarseFrequencies(6)), 0.5, 10));
        end
        
        function app = setFineFrequencyKnobs(app)
            setKnobWithLimits(app, app.Osc1Knob_2, app.fineFrequencies(1));
            setKnobWithLimits(app, app.Osc2Knob_2, app.fineFrequencies(2));
            setKnobWithLimits(app, app.Osc3Knob_2, app.fineFrequencies(3));
            setKnobWithLimits(app, app.Osc4Knob_2, app.fineFrequencies(4));
            setKnobWithLimits(app, app.Osc5Knob_2, app.fineFrequencies(5));
            setKnobWithLimits(app, app.Osc6Knob_2, app.fineFrequencies(6));
            
        end
        
        function app = setModEnvAmtKnobs(app)
            setKnobWithLimits(app, app.Osc1Knob_3, app.modEnvAmt(1));
            setKnobWithLimits(app, app.Osc2Knob_3, app.modEnvAmt(2));
            setKnobWithLimits(app, app.Osc3Knob_3, app.modEnvAmt(3));
            setKnobWithLimits(app, app.Osc4Knob_3, app.modEnvAmt(4));
            setKnobWithLimits(app, app.Osc5Knob_3, app.modEnvAmt(5));
            setKnobWithLimits(app, app.Osc6Knob_3, app.modEnvAmt(6));
        end
        
        function app = setLfoADepthKnobs(app)
            setKnobWithLimits(app, app.Osc1Knob_4, app.lfoADepth(1));
            setKnobWithLimits(app, app.Osc2Knob_4, app.lfoADepth(2));
            setKnobWithLimits(app, app.Osc3Knob_4, app.lfoADepth(3));
            setKnobWithLimits(app, app.Osc4Knob_4, app.lfoADepth(4));
            setKnobWithLimits(app, app.Osc5Knob_4, app.lfoADepth(5));
            setKnobWithLimits(app, app.Osc6Knob_4, app.lfoADepth(6));
        end
        
        function app = setLfoBDepthKnobs(app)
            setKnobWithLimits(app, app.Osc1Knob_5, app.lfoBDepth(1));
            setKnobWithLimits(app, app.Osc2Knob_5, app.lfoBDepth(2));
            setKnobWithLimits(app, app.Osc3Knob_5, app.lfoBDepth(3));
            setKnobWithLimits(app, app.Osc4Knob_5, app.lfoBDepth(4));
            setKnobWithLimits(app, app.Osc5Knob_5, app.lfoBDepth(5));
            setKnobWithLimits(app, app.Osc6Knob_5, app.lfoBDepth(6));
            
        end
        
        function app = setOutputLevelSliders(app)
            setKnobWithLimits(app, app.Osc1Slider, app.outputLevels(1));
            setKnobWithLimits(app, app.Osc2Slider, app.outputLevels(2));
            setKnobWithLimits(app, app.Osc3Slider, app.outputLevels(3));
            setKnobWithLimits(app, app.Osc4Slider, app.outputLevels(4));
            setKnobWithLimits(app, app.Osc5Slider, app.outputLevels(5));
            setKnobWithLimits(app, app.Osc6Slider, app.outputLevels(6));
            
        end
        
        function app = setPMparamsKnobs(app)
            setKnobWithLimits(app, app.Osc11Knob, app.PMparams(1));
            setKnobWithLimits(app, app.Osc21Knob, app.PMparams(2));
            setKnobWithLimits(app, app.Osc31Knob, app.PMparams(3));
            setKnobWithLimits(app, app.Osc41Knob, app.PMparams(4));
            setKnobWithLimits(app, app.Osc51Knob, app.PMparams(5));
            setKnobWithLimits(app, app.Osc61Knob, app.PMparams(6));
            
            setKnobWithLimits(app, app.Osc12Knob, app.PMparams(7));
            setKnobWithLimits(app, app.Osc22Knob, app.PMparams(8));
            setKnobWithLimits(app, app.Osc32Knob, app.PMparams(9));
            setKnobWithLimits(app, app.Osc42Knob, app.PMparams(10));
            setKnobWithLimits(app, app.Osc52Knob, app.PMparams(11));
            setKnobWithLimits(app, app.Osc62Knob, app.PMparams(12));
            
            setKnobWithLimits(app, app.Osc13Knob, app.PMparams(13));
            setKnobWithLimits(app, app.Osc23Knob, app.PMparams(14));
            setKnobWithLimits(app, app.Osc33Knob, app.PMparams(15));
            setKnobWithLimits(app, app.Osc43Knob, app.PMparams(16));
            setKnobWithLimits(app, app.Osc53Knob, app.PMparams(17));
            setKnobWithLimits(app, app.Osc63Knob, app.PMparams(18));
            
            setKnobWithLimits(app, app.Osc14Knob, app.PMparams(19));
            setKnobWithLimits(app, app.Osc24Knob, app.PMparams(20));
            setKnobWithLimits(app, app.Osc34Knob, app.PMparams(21));
            setKnobWithLimits(app, app.Osc44Knob, app.PMparams(22));
            setKnobWithLimits(app, app.Osc54Knob, app.PMparams(23));
            setKnobWithLimits(app, app.Osc64Knob, app.PMparams(24));
            
            setKnobWithLimits(app, app.Osc15Knob, app.PMparams(25));
            setKnobWithLimits(app, app.Osc25Knob, app.PMparams(26));
            setKnobWithLimits(app, app.Osc35Knob, app.PMparams(27));
            setKnobWithLimits(app, app.Osc45Knob, app.PMparams(28));
            setKnobWithLimits(app, app.Osc55Knob, app.PMparams(29));
            setKnobWithLimits(app, app.Osc65Knob, app.PMparams(30));
            
            setKnobWithLimits(app, app.Osc16Knob, app.PMparams(31));
            setKnobWithLimits(app, app.Osc26Knob, app.PMparams(32));
            setKnobWithLimits(app, app.Osc36Knob, app.PMparams(33));
            setKnobWithLimits(app, app.Osc46Knob, app.PMparams(34));
            setKnobWithLimits(app, app.Osc56Knob, app.PMparams(35));
            setKnobWithLimits(app, app.Osc66Knob, app.PMparams(36));
        end
        
        function app = setLfoAParamsKnobs(app)
            setKnobWithLimits(app, app.LfoARateKnob, app.lfoAParams(1));
            setKnobWithLimits(app, app.LfoAAmpKnob, app.lfoAParams(2));
            setKnobWithLimits(app, app.LfoAPhaseSpreadKnob, app.lfoAParams(3));
        end
        
        function app = setLfoBParamsKnobs(app)
            setKnobWithLimits(app, app.LfoBRateKnob, app.lfoBParams(1));
            setKnobWithLimits(app, app.LfoBAmpKnob, app.lfoBParams(2));
            setKnobWithLimits(app, app.LfoBPulseWidthKnob, app.lfoBParams(3));
        end
        
        function app = setAmpEnvParamsKnobs(app)
            setKnobWithLimits(app, app.AmpASlider, app.envAmpParams(1));
            setKnobWithLimits(app, app.AmpDSlider, app.envAmpParams(2));
            setKnobWithLimits(app, app.AmpSSlider, app.envAmpParams(3));
            setKnobWithLimits(app, app.AmpRSlider, app.envAmpParams(4));
            setKnobWithLimits(app, app.AmpCurveKnob, app.envAmpParams(5));
        end
        
        function app = setModEnvParamsKnobs(app)
            setKnobWithLimits(app, app.ModASlider, app.env1Params(1));
            setKnobWithLimits(app, app.ModDSlider, app.env1Params(2));
            setKnobWithLimits(app, app.ModSSlider, app.env1Params(3));
            setKnobWithLimits(app, app.ModRSlider, app.env1Params(4));
            setKnobWithLimits(app, app.ModCurveKnob, app.env1Params(5));
        end
        
        function app = setGlobalModifierKnobs(app)
            setKnobWithLimits(app, app.StereoSpreadKnob, app.misc(1));
        end
        
        function app = setVibratoKnobs(app)
            setKnobWithLimits(app, app.VibStartRateKnob, app.misc(2));
            setKnobWithLimits(app, app.VibEndRateKnob, app.misc(3));
            setKnobWithLimits(app, app.VibStartAmtKnob, app.misc(4));
            setKnobWithLimits(app, app.VibEndAmtKnob, app.misc(5));
            setKnobWithLimits(app, app.VibEnvTimeKnob, app.misc(6));
        end
        
        
        function app = updateLfoAPlot(app)
            [x,y] = triangleWave(app.lfoAParams(1), app.lfoAParams(2));
            app.lfoAPlot.XData = x;
            app.lfoAPlot.YData = y;
        end
        
        function app = updateLfoBPlot(app)
            [x,y] = squareWave(app.lfoBParams(1), app.lfoBParams(2), app.lfoBParams(3));
            app.lfoBPlot.XData = x;
            app.lfoBPlot.YData = y;
        end

        function app = updateAmpEnvPlot(app)
            [x,y] = ADSR(app.envAmpParams(1), app.envAmpParams(2), app.envAmpParams(3), app.envAmpParams(4), app.envAmpParams(5));
            app.ampEnvPlot.XData = x;
            app.ampEnvPlot.YData = y;
        end
        
        function app = updateModEnvPlot(app)
            [x,y] = ADSR(app.env1Params(1), app.env1Params(2), app.env1Params(3), app.env1Params(4), app.env1Params(5));
            app.modEnvPlot.XData = x;
            app.modEnvPlot.YData = y;
        end
        
        function app = updateAllKnobs(app)
            setCoarseFrequencyKnobs(app);
            setFineFrequencyKnobs(app);
            setModEnvAmtKnobs(app);
            setLfoADepthKnobs(app);
            setLfoBDepthKnobs(app);
            setOutputLevelSliders(app);
            
            setPMparamsKnobs(app);
            
            setLfoAParamsKnobs(app);
            setLfoBParamsKnobs(app);
            setAmpEnvParamsKnobs(app);
            setModEnvParamsKnobs(app);
            setVibratoKnobs(app);
            setGlobalModifierKnobs(app);
            
        end
        
        function app = loadSelectedPreset(app)
            preset = app.presetRead.presetStore(app.selectedPreset,:);
            
            app = loadPreset(app, preset);
            
%             app.PMparams = preset{1};
%             app.coarseFrequencies = preset{2};
%             app.fineFrequencies = max(0, preset{3});
%             app.outputLevels = preset{4};
%             app.modEnvAmt = preset{5};
%             app.lfoADepth = preset{6};
%             app.lfoBDepth = preset{7};
%             app.lfoAParams = max(0, preset{8});
%             app.lfoBParams = max(0, preset{9});
%             app.envAmpParams = max(0, preset{10});
%             app.env1Params = max(0, preset{11});
%             app.misc = max(0, preset{12});
%             
%             updateAllKnobs(app);
%             sendAllParametersOverOSC(app);
%             
%             updateLfoAPlot(app);
%             updateLfoBPlot(app);
%             updateAmpEnvPlot(app);
%             updateModEnvPlot(app);
        end
        
        function UIelement = setKnobWithLimits(app, UIelement, value)
            UIelement.Value = bound(value, UIelement.Limits(1), UIelement.Limits(2));
        end
        
        function results = sendAllParametersOverOSC(app)
            sendParamsOverOSC(app.coarseFrequencies, '/freqCoarse', 'ffffff', app.UDP)
            sendParamsOverOSC(app.fineFrequencies, '/freqFine', 'ffffff', app.UDP)
            sendParamsOverOSC(app.modEnvAmt, '/envAmt', 'ffffff', app.UDP)
            sendParamsOverOSC(app.lfoADepth, '/lfoADepth', 'ffffff', app.UDP)
            sendParamsOverOSC(app.lfoBDepth, '/lfoBDepth', 'ffffff', app.UDP)
            sendParamsOverOSC(app.outputLevels, '/outputLevels', 'ffffff', app.UDP)
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            sendParamsOverOSC(app.envAmpParams, '/envAmpParams', 'fffff', app.UDP)
            sendParamsOverOSC(app.env1Params, '/env1Params', 'fffff', app.UDP)
            sendParamsOverOSC(app.lfoAParams, '/lfoAParams', 'fff', app.UDP)
            sendParamsOverOSC(app.lfoBParams, '/lfoBParams', 'fff', app.UDP)
            sendParamsOverOSC(app.misc, '/misc', 'ffffff', app.UDP)
        end
        
        function preset = appPresetToStructPreset(app)
            preset = cell(1,12);
            preset{1} = app.PMparams;
            preset{2} = app.coarseFrequencies;
            preset{3} = app.fineFrequencies;
            preset{4} = app.outputLevels;
            preset{5} = app.modEnvAmt;
            preset{6} = app.lfoADepth;
            preset{7} = app.lfoBDepth;
            preset{8} = app.lfoAParams;
            preset{9} = app.lfoBParams;
            preset{10} = app.envAmpParams;
            preset{11} = app.env1Params;
            preset{12} = app.misc;
        end
        
        function app = updateTimePlotsFromUIelement(app)
            preset = appPresetToStructPreset(app);
            
            % Update Time Plots
            app.pcaAppData.timeData = timePlotDataFromPreset(preset);
            app.pcaAppData.timePlots = updateTimePlots(app.pcaAppData.timePlots, app.pcaAppData.timeData); 
        
        end
        
        function app = updateTimbrePlotsFromUIelement(app)
            preset = appPresetToStructPreset(app);
            
            % Update Time Plots
            app.pcaAppData.timbreData = timbrePlotDataFromPreset(preset);
            app.pcaAppData.timbrePlots = updateTimbrePlots(app.pcaAppData.timbrePlots, app.pcaAppData.timbreData); 
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UDP = udp('127.0.0.1',57120); 
            fopen(app.UDP); 
            
            app.presetRead = matfile('PresetStoreSCPermuted.mat');
            app.presetSave = matfile('PresetStoreSCPermuted.mat','Writable',true);
            
            app.PresetSpinner.Limits(2) = length(app.presetSave.presetStore(:,1));
            
            app.selectedPreset = 1;
            
            loadSelectedPreset(app);
            
            % initialise Knobs with corect values
            updateAllKnobs(app);
            
            % Send initialised preset over OSC
            sendAllParametersOverOSC(app);
            
            %set up plots
            % LFO A
            [x1,y1] = triangleWave(app.lfoAParams(1), app.lfoAParams(2));
            app.lfoAPlot = plot(app.LfoAAxes, x1, y1, 'LineWidth', 3) ;
            xlim(app.LfoAAxes,[0, 1]) ; 
            ylim(app.LfoAAxes,[-1, 1]) ; 
            
            % LFO B
            [x2,y2] = squareWave(app.lfoBParams(1), app.lfoBParams(2), app.lfoBParams(3));
            app.lfoBPlot = plot(app.LfoBAxes, x2, y2, 'LineWidth', 3);
            xlim(app.LfoBAxes,[0, 1]) ; 
            ylim(app.LfoBAxes,[-1, 1]) ; 
            
            % Envelopes
            ampColour = [70, 130, 220]/256;
            modColour = [220, 70, 70]/256;
            [ampX, ampY] = ADSR(app.envAmpParams(1), app.envAmpParams(2), app.envAmpParams(3), app.envAmpParams(4), app.envAmpParams(4));
            [modX, modY] = ADSR(app.env1Params(1), app.env1Params(2), app.env1Params(3), app.env1Params(4), app.env1Params(5));
            app.modEnvPlot = plot(app.EnvelopesAxes, modX, modY, 'LineWidth', 3, 'Color', modColour);
            hold(app.EnvelopesAxes);
            app.ampEnvPlot = plot(app.EnvelopesAxes, ampX, ampY, 'LineWidth', 3, 'Color', ampColour);
            xlim(app.EnvelopesAxes, [0, 8])
            ylim(app.EnvelopesAxes, [0, 1])
            set(app.EnvelopesAxes, 'xtick', [])
            
            % Window Position
            screenSize = get(0, 'ScreenSize');
            screenSize = screenSize(3:4);
            
            app.UIFigure.Position(1:2) = [250, screenSize(2)-740];
            
            % Create the other 2 UIs (pcaAppData contains BlendingAppData)
            app.pcaAppData = ApplicationDataPCAInterface(app);
            
            % Hide and Show relevant windows
            pause(2);
            app.UIFigure.Visible = 'off';
            
            figure(7),figure(6),figure(5);
        end

        % Value changed function: Osc1Knob
        function Osc1KnobValueChanged(app, event)
            app.coarseFrequencies(1) = str2double(app.Osc1Knob.Value);
            %disp(mat2str(app.coarseFrequencies))
            sendParamsOverOSC(app.coarseFrequencies, '/freqCoarse', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changed function: Osc2Knob
        function Osc2KnobValueChanged(app, event)
            app.coarseFrequencies(2) = str2double(app.Osc2Knob.Value);
            sendParamsOverOSC(app.coarseFrequencies, '/freqCoarse', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changed function: Osc3Knob
        function Osc3KnobValueChanged(app, event)
            app.coarseFrequencies(3) = str2double(app.Osc3Knob.Value);
            sendParamsOverOSC(app.coarseFrequencies, '/freqCoarse', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changed function: Osc4Knob
        function Osc4KnobValueChanged(app, event)
            app.coarseFrequencies(4) = str2double(app.Osc4Knob.Value);
            sendParamsOverOSC(app.coarseFrequencies, '/freqCoarse', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changed function: Osc5Knob
        function Osc5KnobValueChanged(app, event)
            app.coarseFrequencies(5) = str2double(app.Osc5Knob.Value);
            sendParamsOverOSC(app.coarseFrequencies, '/freqCoarse', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changed function: Osc6Knob
        function Osc6KnobValueChanged(app, event)
            app.coarseFrequencies(6) = str2double(app.Osc6Knob.Value);
            sendParamsOverOSC(app.coarseFrequencies, '/freqCoarse', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc1Knob_2
        function Osc1Knob_2ValueChanging(app, event)
            app.fineFrequencies(1) = event.Value;
            %disp(mat2str(app.fineFrequencies))
            sendParamsOverOSC(app.fineFrequencies, '/freqFine', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc2Knob_2
        function Osc2Knob_2ValueChanging(app, event)
            app.fineFrequencies(2) = event.Value;
            sendParamsOverOSC(app.fineFrequencies, '/freqFine', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc3Knob_2
        function Osc3Knob_2ValueChanging(app, event)
            app.fineFrequencies(3) = event.Value;
            sendParamsOverOSC(app.fineFrequencies, '/freqFine', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc4Knob_2
        function Osc4Knob_2ValueChanging(app, event)
            app.fineFrequencies(4) = event.Value;
            sendParamsOverOSC(app.fineFrequencies, '/freqFine', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc5Knob_2
        function Osc5Knob_2ValueChanging(app, event)
            app.fineFrequencies(5) = event.Value;
            sendParamsOverOSC(app.fineFrequencies, '/freqFine', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc6Knob_2
        function Osc6Knob_2ValueChanging(app, event)
            app.fineFrequencies(6) = event.Value;
            sendParamsOverOSC(app.fineFrequencies, '/freqFine', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc1Knob_3
        function Osc1Knob_3ValueChanging(app, event)
            app.modEnvAmt(1) = event.Value;
            sendParamsOverOSC(app.modEnvAmt, '/envAmt', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc2Knob_3
        function Osc2Knob_3ValueChanging(app, event)
            app.modEnvAmt(2) = event.Value;
            sendParamsOverOSC(app.modEnvAmt, '/envAmt', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc3Knob_3
        function Osc3Knob_3ValueChanging(app, event)
            app.modEnvAmt(3) = event.Value;
            sendParamsOverOSC(app.modEnvAmt, '/envAmt', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc4Knob_3
        function Osc4Knob_3ValueChanging(app, event)
            app.modEnvAmt(4) = event.Value;
            sendParamsOverOSC(app.modEnvAmt, '/envAmt', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc5Knob_3
        function Osc5Knob_3ValueChanging(app, event)
            app.modEnvAmt(5) = event.Value;
            sendParamsOverOSC(app.modEnvAmt, '/envAmt', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc6Knob_3
        function Osc6Knob_3ValueChanging(app, event)
            app.modEnvAmt(6) = event.Value;
            sendParamsOverOSC(app.modEnvAmt, '/envAmt', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc1Knob_4
        function Osc1Knob_4ValueChanging(app, event)
            app.lfoADepth(1) = event.Value;
            sendParamsOverOSC(app.lfoADepth, '/lfoADepth', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc2Knob_4
        function Osc2Knob_4ValueChanging(app, event)
            app.lfoADepth(2) = event.Value;
            sendParamsOverOSC(app.lfoADepth, '/lfoADepth', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc3Knob_4
        function Osc3Knob_4ValueChanging(app, event)
            app.lfoADepth(3) = event.Value;
            sendParamsOverOSC(app.lfoADepth, '/lfoADepth', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc4Knob_4
        function Osc4Knob_4ValueChanging(app, event)
            app.lfoADepth(4) = event.Value;
            sendParamsOverOSC(app.lfoADepth, '/lfoADepth', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc5Knob_4
        function Osc5Knob_4ValueChanging(app, event)
            app.lfoADepth(5) = event.Value;
            sendParamsOverOSC(app.lfoADepth, '/lfoADepth', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc6Knob_4
        function Osc6Knob_4ValueChanging(app, event)
            app.lfoADepth(6) = event.Value;
            sendParamsOverOSC(app.lfoADepth, '/lfoADepth', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc1Knob_5
        function Osc1Knob_5ValueChanging(app, event)
            app.lfoBDepth(1) = event.Value;
            sendParamsOverOSC(app.lfoBDepth, '/lfoBDepth', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc2Knob_5
        function Osc2Knob_5ValueChanging(app, event)
            app.lfoBDepth(2) = event.Value;
            sendParamsOverOSC(app.lfoBDepth, '/lfoBDepth', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc3Knob_5
        function Osc3Knob_5ValueChanging(app, event)
            app.lfoBDepth(3) = event.Value;
            sendParamsOverOSC(app.lfoBDepth, '/lfoBDepth', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc4Knob_5
        function Osc4Knob_5ValueChanging(app, event)
            app.lfoBDepth(4) = event.Value;
            sendParamsOverOSC(app.lfoBDepth, '/lfoBDepth', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc5Knob_5
        function Osc5Knob_5ValueChanging(app, event)
            app.lfoBDepth(5) = event.Value;
            sendParamsOverOSC(app.lfoBDepth, '/lfoBDepth', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc6Knob_5
        function Osc6Knob_5ValueChanging(app, event)
            app.lfoBDepth(6) = event.Value;
            sendParamsOverOSC(app.lfoBDepth, '/lfoBDepth', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc1Slider
        function Osc1SliderValueChanging(app, event)
            app.outputLevels(1) = event.Value;
            sendParamsOverOSC(app.outputLevels, '/outputLevels', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc2Slider
        function Osc2SliderValueChanging(app, event)
            app.outputLevels(2) = event.Value;
            sendParamsOverOSC(app.outputLevels, '/outputLevels', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc3Slider
        function Osc3SliderValueChanging(app, event)
            app.outputLevels(3) = event.Value;
            sendParamsOverOSC(app.outputLevels, '/outputLevels', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc4Slider
        function Osc4SliderValueChanging(app, event)
            app.outputLevels(4) = event.Value;
            sendParamsOverOSC(app.outputLevels, '/outputLevels', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc5Slider
        function Osc5SliderValueChanging(app, event)
            app.outputLevels(5) = event.Value;
            sendParamsOverOSC(app.outputLevels, '/outputLevels', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc6Slider
        function Osc6SliderValueChanging(app, event)
            app.outputLevels(6) = event.Value;
            sendParamsOverOSC(app.outputLevels, '/outputLevels', 'ffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc11Knob
        function Osc11KnobValueChanging(app, event)
            app.PMparams(1) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc21Knob
        function Osc21KnobValueChanging(app, event)
            app.PMparams(2) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc31Knob
        function Osc31KnobValueChanging(app, event)
            app.PMparams(3) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc41Knob
        function Osc41KnobValueChanging(app, event)
            app.PMparams(4) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc51Knob
        function Osc51KnobValueChanging(app, event)
            app.PMparams(5) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc61Knob
        function Osc61KnobValueChanging(app, event)
            app.PMparams(6) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc12Knob
        function Osc12KnobValueChanging(app, event)
            app.PMparams(7) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc22Knob
        function Osc22KnobValueChanging(app, event)
            app.PMparams(8) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc32Knob
        function Osc32KnobValueChanging(app, event)
            app.PMparams(9) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc42Knob
        function Osc42KnobValueChanging(app, event)
            app.PMparams(10) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc52Knob
        function Osc52KnobValueChanging(app, event)
            app.PMparams(11) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc62Knob
        function Osc62KnobValueChanging(app, event)
            app.PMparams(12) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc13Knob
        function Osc13KnobValueChanging(app, event)
            app.PMparams(13) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc23Knob
        function Osc23KnobValueChanging(app, event)
            app.PMparams(14) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc33Knob
        function Osc33KnobValueChanging(app, event)
            app.PMparams(15) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc43Knob
        function Osc43KnobValueChanging(app, event)
            app.PMparams(16) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc53Knob
        function Osc53KnobValueChanging(app, event)
            app.PMparams(17) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc63Knob
        function Osc63KnobValueChanging(app, event)
            app.PMparams(18) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc14Knob
        function Osc14KnobValueChanging(app, event)
            app.PMparams(19) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc24Knob
        function Osc24KnobValueChanging(app, event)
            app.PMparams(20) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc34Knob
        function Osc34KnobValueChanging(app, event)
            app.PMparams(21) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc44Knob
        function Osc44KnobValueChanging(app, event)
            app.PMparams(22) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc54Knob
        function Osc54KnobValueChanging(app, event)
            app.PMparams(23) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc64Knob
        function Osc64KnobValueChanging(app, event)
            app.PMparams(24) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc15Knob
        function Osc15KnobValueChanging(app, event)
            app.PMparams(25) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc25Knob
        function Osc25KnobValueChanging(app, event)
            app.PMparams(26) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc35Knob
        function Osc35KnobValueChanging(app, event)
            app.PMparams(27) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc45Knob
        function Osc45KnobValueChanging(app, event)
            app.PMparams(28) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc55Knob
        function Osc55KnobValueChanging(app, event)
            app.PMparams(29) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc65Knob
        function Osc65KnobValueChanging(app, event)
            app.PMparams(30) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc16Knob
        function Osc16KnobValueChanging(app, event)
            app.PMparams(31) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc26Knob
        function Osc26KnobValueChanging(app, event)
            app.PMparams(32) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc36Knob
        function Osc36KnobValueChanging(app, event)
            app.PMparams(33) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc46Knob
        function Osc46KnobValueChanging(app, event)
            app.PMparams(34) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc56Knob
        function Osc56KnobValueChanging(app, event)
            app.PMparams(35) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: Osc66Knob
        function Osc66KnobValueChanging(app, event)
            app.PMparams(36) = event.Value;
            sendParamsOverOSC(app.PMparams, '/PMparams',...
                'ffffffffffffffffffffffffffffffffffff', app.UDP)
            app = updateTimbrePlotsFromUIelement(app);
        end

        % Value changing function: LfoARateKnob
        function LfoARateKnobValueChanging(app, event)
            app.lfoAParams(1) = event.Value;
            sendParamsOverOSC(app.lfoAParams, '/lfoAParams', 'fff', app.UDP)
            updateLfoAPlot(app);
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: LfoAAmpKnob
        function LfoAAmpKnobValueChanging(app, event)
            app.lfoAParams(2) = event.Value;
            sendParamsOverOSC(app.lfoAParams, '/lfoAParams', 'fff', app.UDP)
            updateLfoAPlot(app);
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: LfoAPhaseSpreadKnob
        function LfoAPhaseSpreadKnobValueChanging(app, event)
            app.lfoAParams(3) = event.Value;
            sendParamsOverOSC(app.lfoAParams, '/lfoAParams', 'fff', app.UDP)
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: LfoBRateKnob
        function LfoBRateKnobValueChanging(app, event)
            app.lfoBParams(1) = event.Value;
            sendParamsOverOSC(app.lfoBParams, '/lfoBParams', 'fff', app.UDP)
            updateLfoBPlot(app);
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: LfoBAmpKnob
        function LfoBAmpKnobValueChanging(app, event)
            app.lfoBParams(2) = event.Value;
            sendParamsOverOSC(app.lfoBParams, '/lfoBParams', 'fff', app.UDP)
            updateLfoBPlot(app);
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: LfoBPulseWidthKnob
        function LfoBPulseWidthKnobValueChanging(app, event)
            app.lfoBParams(3) = event.Value;
            sendParamsOverOSC(app.lfoBParams, '/lfoBParams', 'fff', app.UDP)
            updateLfoBPlot(app);
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: AmpASlider
        function AmpASliderValueChanging(app, event)
            app.envAmpParams(1) = event.Value;
            sendParamsOverOSC(app.envAmpParams, '/envAmpParams', 'fffff', app.UDP)
            updateAmpEnvPlot(app);
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: AmpDSlider
        function AmpDSliderValueChanging(app, event)
            app.envAmpParams(2) = event.Value;
            sendParamsOverOSC(app.envAmpParams, '/envAmpParams', 'fffff', app.UDP)
            updateAmpEnvPlot(app);
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: AmpSSlider
        function AmpSSliderValueChanging(app, event)
            app.envAmpParams(3) = event.Value;
            sendParamsOverOSC(app.envAmpParams, '/envAmpParams', 'fffff', app.UDP)
            updateAmpEnvPlot(app);
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: AmpRSlider
        function AmpRSliderValueChanging(app, event)
            app.envAmpParams(4) = event.Value;
            sendParamsOverOSC(app.envAmpParams, '/envAmpParams', 'fffff', app.UDP)
            updateAmpEnvPlot(app);
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: AmpCurveKnob
        function AmpCurveKnobValueChanging(app, event)
            app.envAmpParams(5) = event.Value;
            sendParamsOverOSC(app.envAmpParams, '/envAmpParams', 'fffff', app.UDP)
            updateAmpEnvPlot(app);
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: ModASlider
        function ModASliderValueChanging(app, event)
            app.env1Params(1) = event.Value;
            sendParamsOverOSC(app.env1Params, '/env1Params', 'fffff', app.UDP)
            updateModEnvPlot(app);
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: ModDSlider
        function ModDSliderValueChanging(app, event)
            app.env1Params(2) = event.Value;
            sendParamsOverOSC(app.env1Params, '/env1Params', 'fffff', app.UDP)
            updateModEnvPlot(app);
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: ModSSlider
        function ModSSliderValueChanging(app, event)
            app.env1Params(3) = event.Value;
            sendParamsOverOSC(app.env1Params, '/env1Params', 'fffff', app.UDP)
            updateModEnvPlot(app);
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: ModRSlider
        function ModRSliderValueChanging(app, event)
            app.env1Params(4) = event.Value;
            sendParamsOverOSC(app.env1Params, '/env1Params', 'fffff', app.UDP)
            updateModEnvPlot(app);
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: ModCurveKnob
        function ModCurveKnobValueChanging(app, event)
            app.env1Params(5) = event.Value;
            sendParamsOverOSC(app.env1Params, '/env1Params', 'fffff', app.UDP)
            updateModEnvPlot(app);
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: StereoSpreadKnob
        function StereoSpreadKnobValueChanging(app, event)
            app.misc(1) = event.Value;
            sendParamsOverOSC(app.misc, '/misc', 'ffffff', app.UDP)
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: VibStartRateKnob
        function VibStartRateKnobValueChanging(app, event)
            app.misc(2) = event.Value;
            sendParamsOverOSC(app.misc, '/misc', 'ffffff', app.UDP)
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: VibEndRateKnob
        function VibEndRateKnobValueChanging(app, event)
            app.misc(3) = event.Value;
            sendParamsOverOSC(app.misc, '/misc', 'ffffff', app.UDP)
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: VibStartAmtKnob
        function VibStartAmtKnobValueChanging(app, event)
            app.misc(4) = max(0.001, event.Value);
            sendParamsOverOSC(app.misc, '/misc', 'ffffff', app.UDP)
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: VibEndAmtKnob
        function VibEndAmtKnobValueChanging(app, event)
            app.misc(5) = max(0.001, event.Value);
            sendParamsOverOSC(app.misc, '/misc', 'ffffff', app.UDP)
            app = updateTimePlotsFromUIelement(app);
        end

        % Value changing function: VibEnvTimeKnob
        function VibEnvTimeKnobValueChanging(app, event)
            app.misc(6) = event.Value;
            sendParamsOverOSC(app.misc, '/misc', 'ffffff', app.UDP)
            app = updateTimePlotsFromUIelement(app);
        end

        % Button pushed function: LoadSelectedPresetButton
        function LoadSelectedPresetButtonPushed(app, event)
            loadSelectedPreset(app);
        end

        % Button pushed function: ReWriteSelectedPresetButton
        function ReWriteSelectedPresetButtonPushed(app, event)
            app.presetSave.presetStore(app.selectedPreset, :) = ...
                {app.PMparams, app.coarseFrequencies, app.fineFrequencies, ...
                app.outputLevels, app.modEnvAmt, app.lfoADepth, app.lfoBDepth,...
                app.lfoAParams, app.lfoBParams, app.envAmpParams, app.env1Params, app.misc};
            
        end

        % Button pushed function: SaveasNewPresetButton
        function SaveasNewPresetButtonPushed(app, event)
            app.presetSave.presetStore(1+length(app.presetSave.presetStore(:,1)),:) = ...
                {app.PMparams, app.coarseFrequencies, app.fineFrequencies, ...
                app.outputLevels, app.modEnvAmt, app.lfoADepth, app.lfoBDepth,...
                app.lfoAParams, app.lfoBParams, app.envAmpParams, app.env1Params, app.misc};
            
                app.PresetSpinner.Limits(2) = length(app.presetSave.presetStore(:,1));
            
        end

        % Value changed function: PresetSpinner
        function PresetSpinnerValueChanged(app, event)
            app.selectedPreset = app.PresetSpinner.Value;
            %disp(app.selectedPreset);
        end

        % Button pushed function: ReturntoSelectionUIButton
        function ReturntoSelectionUIButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            %figure(7), figure(6), figure(5);
            preset = appPresetToStructPreset(app);
            % Create logic to send altered preset back to other UI
            if isequal(app.pcaAppData.lastSelectedPresetType, 'Original')
                app.pcaAppData.presetStoreEdited(app.pcaAppData.idxSelected(end),:) = preset;
                app.pcaAppData.presetStoreVaried(app.pcaAppData.idxSelected(end),:) = preset;
                
                app.pcaAppData.presetPCAParams{app.pcaAppData.idxSelected(end)} = zeros(4);
                
            elseif isequal(app.pcaAppData.lastSelectedPresetType, 'Combined')
                app.pcaAppData.combinedPresetsEdited{app.pcaAppData.combinedMarkerLastClicked} = preset;
                app.pcaAppData.combinedPresetsVaried{app.pcaAppData.combinedMarkerLastClicked} = preset;
                
                app.pcaAppData.combinedPresetPCAParams{app.pcaAppData.combinedMarkerLastClicked} = zeros(4);
            else
                error('Incorrect Preset Type')
            end
            
            % Reset Sliders
            updateSliders(zeros(4), app.pcaAppData);
            
            % Reset NumDisplays
            updateNumDisplays(zeros(4), app.pcaAppData);
            
            app.pcaAppData.undoEditButton.Enable = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimbreControls1Tab = uitab(app.TabGroup);
            app.TimbreControls1Tab.Title = 'Timbre Controls 1 ';
            app.TimbreControls1Tab.Units = 'pixels';
            app.CoarseFrequencyPanel = uipanel(app.TimbreControls1Tab);
            app.CoarseFrequencyPanel.Title = 'Coarse Frequency';
            app.Osc1KnobLabel = uilabel(app.CoarseFrequencyPanel);
            app.Osc1KnobLabel.Text = 'Osc1';
            app.Osc1Knob = uiknob(app.CoarseFrequencyPanel, 'discrete');
            app.Osc1Knob.Items = {'0.5', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Osc1Knob.ValueChangedFcn = createCallbackFcn(app, @Osc1KnobValueChanged, true);
            app.Osc1Knob.Value = '0.5';
            app.Osc6KnobLabel = uilabel(app.CoarseFrequencyPanel);
            app.Osc6KnobLabel.Text = 'Osc6';
            app.Osc6Knob = uiknob(app.CoarseFrequencyPanel, 'discrete');
            app.Osc6Knob.Items = {'0.5', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Osc6Knob.ValueChangedFcn = createCallbackFcn(app, @Osc6KnobValueChanged, true);
            app.Osc6Knob.Value = '0.5';
            app.Osc5KnobLabel = uilabel(app.CoarseFrequencyPanel);
            app.Osc5KnobLabel.Text = 'Osc5';
            app.Osc5Knob = uiknob(app.CoarseFrequencyPanel, 'discrete');
            app.Osc5Knob.Items = {'0.5', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Osc5Knob.ValueChangedFcn = createCallbackFcn(app, @Osc5KnobValueChanged, true);
            app.Osc5Knob.Value = '0.5';
            app.Osc2KnobLabel = uilabel(app.CoarseFrequencyPanel);
            app.Osc2KnobLabel.Text = 'Osc2';
            app.Osc2Knob = uiknob(app.CoarseFrequencyPanel, 'discrete');
            app.Osc2Knob.Items = {'0.5', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Osc2Knob.ValueChangedFcn = createCallbackFcn(app, @Osc2KnobValueChanged, true);
            app.Osc2Knob.Value = '0.5';
            app.Osc3KnobLabel = uilabel(app.CoarseFrequencyPanel);
            app.Osc3KnobLabel.Text = 'Osc3';
            app.Osc3Knob = uiknob(app.CoarseFrequencyPanel, 'discrete');
            app.Osc3Knob.Items = {'0.5', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Osc3Knob.ValueChangedFcn = createCallbackFcn(app, @Osc3KnobValueChanged, true);
            app.Osc3Knob.Value = '0.5';
            app.Osc4KnobLabel = uilabel(app.CoarseFrequencyPanel);
            app.Osc4KnobLabel.Text = 'Osc4';
            app.Osc4Knob = uiknob(app.CoarseFrequencyPanel, 'discrete');
            app.Osc4Knob.Items = {'0.5', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Osc4Knob.ValueChangedFcn = createCallbackFcn(app, @Osc4KnobValueChanged, true);
            app.Osc4Knob.Value = '0.5';
            app.FineFrequencyPanel = uipanel(app.TimbreControls1Tab);
            app.FineFrequencyPanel.Title = 'Fine Frequency';
            app.Osc1Knob_2Label = uilabel(app.FineFrequencyPanel);
            app.Osc1Knob_2Label.Text = 'Osc1';
            app.Osc1Knob_2 = uiknob(app.FineFrequencyPanel, 'continuous');
            app.Osc1Knob_2.Limits = [0 1];
            app.Osc1Knob_2.ValueChangingFcn = createCallbackFcn(app, @Osc1Knob_2ValueChanging, true);
            app.Osc2Knob_2Label = uilabel(app.FineFrequencyPanel);
            app.Osc2Knob_2Label.Text = 'Osc2';
            app.Osc2Knob_2 = uiknob(app.FineFrequencyPanel, 'continuous');
            app.Osc2Knob_2.Limits = [0 1];
            app.Osc2Knob_2.ValueChangingFcn = createCallbackFcn(app, @Osc2Knob_2ValueChanging, true);
            app.Osc3Knob_2Label = uilabel(app.FineFrequencyPanel);
            app.Osc3Knob_2Label.Text = 'Osc3';
            app.Osc3Knob_2 = uiknob(app.FineFrequencyPanel, 'continuous');
            app.Osc3Knob_2.Limits = [0 1];
            app.Osc3Knob_2.ValueChangingFcn = createCallbackFcn(app, @Osc3Knob_2ValueChanging, true);
            app.Osc4Knob_2Label = uilabel(app.FineFrequencyPanel);
            app.Osc4Knob_2Label.Text = 'Osc4';
            app.Osc4Knob_2 = uiknob(app.FineFrequencyPanel, 'continuous');
            app.Osc4Knob_2.Limits = [0 1];
            app.Osc4Knob_2.ValueChangingFcn = createCallbackFcn(app, @Osc4Knob_2ValueChanging, true);
            app.Osc5Knob_2Label = uilabel(app.FineFrequencyPanel);
            app.Osc5Knob_2Label.Text = 'Osc5';
            app.Osc5Knob_2 = uiknob(app.FineFrequencyPanel, 'continuous');
            app.Osc5Knob_2.Limits = [0 1];
            app.Osc5Knob_2.ValueChangingFcn = createCallbackFcn(app, @Osc5Knob_2ValueChanging, true);
            app.Osc6Knob_2Label = uilabel(app.FineFrequencyPanel);
            app.Osc6Knob_2Label.Text = 'Osc6';
            app.Osc6Knob_2 = uiknob(app.FineFrequencyPanel, 'continuous');
            app.Osc6Knob_2.Limits = [0 1];
            app.Osc6Knob_2.ValueChangingFcn = createCallbackFcn(app, @Osc6Knob_2ValueChanging, true);
            app.ModEnvAmtPanel = uipanel(app.TimbreControls1Tab);
            app.ModEnvAmtPanel.Title = 'Mod Env Amt';
            app.Osc1Knob_3Label = uilabel(app.ModEnvAmtPanel);
            app.Osc1Knob_3Label.Text = 'Osc1';
            app.Osc1Knob_3 = uiknob(app.ModEnvAmtPanel, 'continuous');
            app.Osc1Knob_3.Limits = [0 1];
            app.Osc1Knob_3.MajorTicks = [0 0.5 1];
            app.Osc1Knob_3.ValueChangingFcn = createCallbackFcn(app, @Osc1Knob_3ValueChanging, true);
            app.Osc2Knob_3Label = uilabel(app.ModEnvAmtPanel);
            app.Osc2Knob_3Label.Text = 'Osc2';
            app.Osc2Knob_3 = uiknob(app.ModEnvAmtPanel, 'continuous');
            app.Osc2Knob_3.Limits = [0 1];
            app.Osc2Knob_3.MajorTicks = [0 0.5 1];
            app.Osc2Knob_3.ValueChangingFcn = createCallbackFcn(app, @Osc2Knob_3ValueChanging, true);
            app.Osc3Knob_3Label = uilabel(app.ModEnvAmtPanel);
            app.Osc3Knob_3Label.Text = 'Osc3';
            app.Osc3Knob_3 = uiknob(app.ModEnvAmtPanel, 'continuous');
            app.Osc3Knob_3.Limits = [0 1];
            app.Osc3Knob_3.MajorTicks = [0 0.5 1];
            app.Osc3Knob_3.ValueChangingFcn = createCallbackFcn(app, @Osc3Knob_3ValueChanging, true);
            app.Osc4Knob_3Label = uilabel(app.ModEnvAmtPanel);
            app.Osc4Knob_3Label.Text = 'Osc4';
            app.Osc4Knob_3 = uiknob(app.ModEnvAmtPanel, 'continuous');
            app.Osc4Knob_3.Limits = [0 1];
            app.Osc4Knob_3.MajorTicks = [0 0.5 1];
            app.Osc4Knob_3.ValueChangingFcn = createCallbackFcn(app, @Osc4Knob_3ValueChanging, true);
            app.Osc5Knob_3Label = uilabel(app.ModEnvAmtPanel);
            app.Osc5Knob_3Label.Text = 'Osc5';
            app.Osc5Knob_3 = uiknob(app.ModEnvAmtPanel, 'continuous');
            app.Osc5Knob_3.Limits = [0 1];
            app.Osc5Knob_3.MajorTicks = [0 0.5 1];
            app.Osc5Knob_3.ValueChangingFcn = createCallbackFcn(app, @Osc5Knob_3ValueChanging, true);
            app.Osc6Knob_3Label = uilabel(app.ModEnvAmtPanel);
            app.Osc6Knob_3Label.Text = 'Osc6';
            app.Osc6Knob_3 = uiknob(app.ModEnvAmtPanel, 'continuous');
            app.Osc6Knob_3.Limits = [0 1];
            app.Osc6Knob_3.MajorTicks = [0 0.5 1];
            app.Osc6Knob_3.ValueChangingFcn = createCallbackFcn(app, @Osc6Knob_3ValueChanging, true);
            app.OutputLevelsPanel = uipanel(app.TimbreControls1Tab);
            app.OutputLevelsPanel.Title = 'Output Levels';
            app.Osc1SliderLabel = uilabel(app.OutputLevelsPanel);
            app.Osc1SliderLabel.Text = 'Osc1';
            app.Osc1Slider = uislider(app.OutputLevelsPanel);
            app.Osc1Slider.Limits = [0 1];
            app.Osc1Slider.ValueChangingFcn = createCallbackFcn(app, @Osc1SliderValueChanging, true);
            app.Osc2SliderLabel = uilabel(app.OutputLevelsPanel);
            app.Osc2SliderLabel.Text = 'Osc2';
            app.Osc2Slider = uislider(app.OutputLevelsPanel);
            app.Osc2Slider.Limits = [0 1];
            app.Osc2Slider.ValueChangingFcn = createCallbackFcn(app, @Osc2SliderValueChanging, true);
            app.Osc3SliderLabel = uilabel(app.OutputLevelsPanel);
            app.Osc3SliderLabel.Text = 'Osc3';
            app.Osc3Slider = uislider(app.OutputLevelsPanel);
            app.Osc3Slider.Limits = [0 1];
            app.Osc3Slider.ValueChangingFcn = createCallbackFcn(app, @Osc3SliderValueChanging, true);
            app.Osc4SliderLabel = uilabel(app.OutputLevelsPanel);
            app.Osc4SliderLabel.Text = 'Osc4';
            app.Osc4Slider = uislider(app.OutputLevelsPanel);
            app.Osc4Slider.Limits = [0 1];
            app.Osc4Slider.ValueChangingFcn = createCallbackFcn(app, @Osc4SliderValueChanging, true);
            app.Osc5SliderLabel = uilabel(app.OutputLevelsPanel);
            app.Osc5SliderLabel.Text = 'Osc5';
            app.Osc5Slider = uislider(app.OutputLevelsPanel);
            app.Osc5Slider.Limits = [0 1];
            app.Osc5Slider.ValueChangingFcn = createCallbackFcn(app, @Osc5SliderValueChanging, true);
            app.Osc6SliderLabel = uilabel(app.OutputLevelsPanel);
            app.Osc6SliderLabel.Text = 'Osc6';
            app.Osc6Slider = uislider(app.OutputLevelsPanel);
            app.Osc6Slider.Limits = [0 1];
            app.Osc6Slider.ValueChangingFcn = createCallbackFcn(app, @Osc6SliderValueChanging, true);
            app.LFOADepthPanel = uipanel(app.TimbreControls1Tab);
            app.LFOADepthPanel.Title = 'LFO A Depth';
            app.Osc1Knob_4Label = uilabel(app.LFOADepthPanel);
            app.Osc1Knob_4Label.Text = 'Osc1';
            app.Osc1Knob_4 = uiknob(app.LFOADepthPanel, 'continuous');
            app.Osc1Knob_4.Limits = [0 1];
            app.Osc1Knob_4.MajorTicks = [0 0.5 1];
            app.Osc1Knob_4.ValueChangingFcn = createCallbackFcn(app, @Osc1Knob_4ValueChanging, true);
            app.Osc2Knob_4Label = uilabel(app.LFOADepthPanel);
            app.Osc2Knob_4Label.Text = 'Osc2';
            app.Osc2Knob_4 = uiknob(app.LFOADepthPanel, 'continuous');
            app.Osc2Knob_4.Limits = [0 1];
            app.Osc2Knob_4.MajorTicks = [0 0.5 1];
            app.Osc2Knob_4.ValueChangingFcn = createCallbackFcn(app, @Osc2Knob_4ValueChanging, true);
            app.Osc3Knob_4Label = uilabel(app.LFOADepthPanel);
            app.Osc3Knob_4Label.Text = 'Osc3';
            app.Osc3Knob_4 = uiknob(app.LFOADepthPanel, 'continuous');
            app.Osc3Knob_4.Limits = [0 1];
            app.Osc3Knob_4.MajorTicks = [0 0.5 1];
            app.Osc3Knob_4.ValueChangingFcn = createCallbackFcn(app, @Osc3Knob_4ValueChanging, true);
            app.Osc4Knob_4Label = uilabel(app.LFOADepthPanel);
            app.Osc4Knob_4Label.Text = 'Osc4';
            app.Osc4Knob_4 = uiknob(app.LFOADepthPanel, 'continuous');
            app.Osc4Knob_4.Limits = [0 1];
            app.Osc4Knob_4.MajorTicks = [0 0.5 1];
            app.Osc4Knob_4.ValueChangingFcn = createCallbackFcn(app, @Osc4Knob_4ValueChanging, true);
            app.Osc5Knob_4Label = uilabel(app.LFOADepthPanel);
            app.Osc5Knob_4Label.Text = 'Osc5';
            app.Osc5Knob_4 = uiknob(app.LFOADepthPanel, 'continuous');
            app.Osc5Knob_4.Limits = [0 1];
            app.Osc5Knob_4.MajorTicks = [0 0.5 1];
            app.Osc5Knob_4.ValueChangingFcn = createCallbackFcn(app, @Osc5Knob_4ValueChanging, true);
            app.Osc6Knob_4Label = uilabel(app.LFOADepthPanel);
            app.Osc6Knob_4Label.Text = 'Osc6';
            app.Osc6Knob_4 = uiknob(app.LFOADepthPanel, 'continuous');
            app.Osc6Knob_4.Limits = [0 1];
            app.Osc6Knob_4.MajorTicks = [0 0.5 1];
            app.Osc6Knob_4.ValueChangingFcn = createCallbackFcn(app, @Osc6Knob_4ValueChanging, true);
            app.LFOBDepthPanel = uipanel(app.TimbreControls1Tab);
            app.LFOBDepthPanel.Title = 'LFO B Depth';
            app.Osc1Knob_5Label = uilabel(app.LFOBDepthPanel);
            app.Osc1Knob_5Label.Text = 'Osc1';
            app.Osc1Knob_5 = uiknob(app.LFOBDepthPanel, 'continuous');
            app.Osc1Knob_5.Limits = [0 1];
            app.Osc1Knob_5.MajorTicks = [0 0.5 1];
            app.Osc1Knob_5.ValueChangingFcn = createCallbackFcn(app, @Osc1Knob_5ValueChanging, true);
            app.Osc2Knob_5Label = uilabel(app.LFOBDepthPanel);
            app.Osc2Knob_5Label.Text = 'Osc2';
            app.Osc2Knob_5 = uiknob(app.LFOBDepthPanel, 'continuous');
            app.Osc2Knob_5.Limits = [0 1];
            app.Osc2Knob_5.MajorTicks = [0 0.5 1];
            app.Osc2Knob_5.ValueChangingFcn = createCallbackFcn(app, @Osc2Knob_5ValueChanging, true);
            app.Osc3Knob_5Label = uilabel(app.LFOBDepthPanel);
            app.Osc3Knob_5Label.Text = 'Osc3';
            app.Osc3Knob_5 = uiknob(app.LFOBDepthPanel, 'continuous');
            app.Osc3Knob_5.Limits = [0 1];
            app.Osc3Knob_5.MajorTicks = [0 0.5 1];
            app.Osc3Knob_5.ValueChangingFcn = createCallbackFcn(app, @Osc3Knob_5ValueChanging, true);
            app.Osc4Knob_5Label = uilabel(app.LFOBDepthPanel);
            app.Osc4Knob_5Label.Text = 'Osc4';
            app.Osc4Knob_5 = uiknob(app.LFOBDepthPanel, 'continuous');
            app.Osc4Knob_5.Limits = [0 1];
            app.Osc4Knob_5.MajorTicks = [0 0.5 1];
            app.Osc4Knob_5.ValueChangingFcn = createCallbackFcn(app, @Osc4Knob_5ValueChanging, true);
            app.Osc5Knob_5Label = uilabel(app.LFOBDepthPanel);
            app.Osc5Knob_5Label.Text = 'Osc5';
            app.Osc5Knob_5 = uiknob(app.LFOBDepthPanel, 'continuous');
            app.Osc5Knob_5.Limits = [0 1];
            app.Osc5Knob_5.MajorTicks = [0 0.5 1];
            app.Osc5Knob_5.ValueChangingFcn = createCallbackFcn(app, @Osc5Knob_5ValueChanging, true);
            app.Osc6Knob_5Label = uilabel(app.LFOBDepthPanel);
            app.Osc6Knob_5Label.Text = 'Osc6';
            app.Osc6Knob_5 = uiknob(app.LFOBDepthPanel, 'continuous');
            app.Osc6Knob_5.Limits = [0 1];
            app.Osc6Knob_5.MajorTicks = [0 0.5 1];
            app.Osc6Knob_5.ValueChangingFcn = createCallbackFcn(app, @Osc6Knob_5ValueChanging, true);
            app.TimbreControls2Tab = uitab(app.TabGroup);
            app.TimbreControls2Tab.Title = 'Timbre Controls 2';
            app.TimbreControls2Tab.Units = 'pixels';
            app.PhaseModulationParametersPanel = uipanel(app.TimbreControls2Tab);
            app.PhaseModulationParametersPanel.Title = 'Phase Modulation Parameters';
            app.Osc11KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc11KnobLabel.Text = 'Osc1->1';
            app.Osc11Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc11Knob.Limits = [0 10];
            app.Osc11Knob.MajorTicks = [0 10];
            app.Osc11Knob.ValueChangingFcn = createCallbackFcn(app, @Osc11KnobValueChanging, true);
            app.Osc12KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc12KnobLabel.Text = 'Osc1->2';
            app.Osc12Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc12Knob.Limits = [0 10];
            app.Osc12Knob.MajorTicks = [0 10];
            app.Osc12Knob.ValueChangingFcn = createCallbackFcn(app, @Osc12KnobValueChanging, true);
            app.Osc13KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc13KnobLabel.Text = 'Osc1->3';
            app.Osc13Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc13Knob.Limits = [0 10];
            app.Osc13Knob.MajorTicks = [0 10];
            app.Osc13Knob.ValueChangingFcn = createCallbackFcn(app, @Osc13KnobValueChanging, true);
            app.Osc14KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc14KnobLabel.Text = 'Osc1->4';
            app.Osc14Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc14Knob.Limits = [0 10];
            app.Osc14Knob.MajorTicks = [0 10];
            app.Osc14Knob.ValueChangingFcn = createCallbackFcn(app, @Osc14KnobValueChanging, true);
            app.Osc15KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc15KnobLabel.Text = 'Osc1->5';
            app.Osc15Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc15Knob.Limits = [0 10];
            app.Osc15Knob.MajorTicks = [0 10];
            app.Osc15Knob.ValueChangingFcn = createCallbackFcn(app, @Osc15KnobValueChanging, true);
            app.Osc16KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc16KnobLabel.Text = 'Osc1->6';
            app.Osc16Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc16Knob.Limits = [0 10];
            app.Osc16Knob.MajorTicks = [0 10];
            app.Osc16Knob.ValueChangingFcn = createCallbackFcn(app, @Osc16KnobValueChanging, true);
            app.Osc21KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc21KnobLabel.Text = 'Osc2->1';
            app.Osc21Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc21Knob.Limits = [0 10];
            app.Osc21Knob.MajorTicks = [0 10];
            app.Osc21Knob.ValueChangingFcn = createCallbackFcn(app, @Osc21KnobValueChanging, true);
            app.Osc22KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc22KnobLabel.Text = 'Osc2->2';
            app.Osc22Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc22Knob.Limits = [0 10];
            app.Osc22Knob.MajorTicks = [0 10];
            app.Osc22Knob.ValueChangingFcn = createCallbackFcn(app, @Osc22KnobValueChanging, true);
            app.Osc23KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc23KnobLabel.Text = 'Osc2->3';
            app.Osc23Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc23Knob.Limits = [0 10];
            app.Osc23Knob.MajorTicks = [0 10];
            app.Osc23Knob.ValueChangingFcn = createCallbackFcn(app, @Osc23KnobValueChanging, true);
            app.Osc24KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc24KnobLabel.Text = 'Osc2->4';
            app.Osc24Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc24Knob.Limits = [0 10];
            app.Osc24Knob.MajorTicks = [0 10];
            app.Osc24Knob.ValueChangingFcn = createCallbackFcn(app, @Osc24KnobValueChanging, true);
            app.Osc25KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc25KnobLabel.Text = 'Osc2->5';
            app.Osc25Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc25Knob.Limits = [0 10];
            app.Osc25Knob.MajorTicks = [0 10];
            app.Osc25Knob.ValueChangingFcn = createCallbackFcn(app, @Osc25KnobValueChanging, true);
            app.Osc26KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc26KnobLabel.Text = 'Osc2->6';
            app.Osc26Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc26Knob.Limits = [0 10];
            app.Osc26Knob.MajorTicks = [0 10];
            app.Osc26Knob.ValueChangingFcn = createCallbackFcn(app, @Osc26KnobValueChanging, true);
            app.Osc31KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc31KnobLabel.Text = 'Osc3->1';
            app.Osc31Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc31Knob.Limits = [0 10];
            app.Osc31Knob.MajorTicks = [0 10];
            app.Osc31Knob.ValueChangingFcn = createCallbackFcn(app, @Osc31KnobValueChanging, true);
            app.Osc32KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc32KnobLabel.Text = 'Osc3->2';
            app.Osc32Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc32Knob.Limits = [0 10];
            app.Osc32Knob.MajorTicks = [0 10];
            app.Osc32Knob.ValueChangingFcn = createCallbackFcn(app, @Osc32KnobValueChanging, true);
            app.Osc33KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc33KnobLabel.Text = 'Osc3->3';
            app.Osc33Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc33Knob.Limits = [0 10];
            app.Osc33Knob.MajorTicks = [0 10];
            app.Osc33Knob.ValueChangingFcn = createCallbackFcn(app, @Osc33KnobValueChanging, true);
            app.Osc34KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc34KnobLabel.Text = 'Osc3->4';
            app.Osc34Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc34Knob.Limits = [0 10];
            app.Osc34Knob.MajorTicks = [0 10];
            app.Osc34Knob.ValueChangingFcn = createCallbackFcn(app, @Osc34KnobValueChanging, true);
            app.Osc35KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc35KnobLabel.Text = 'Osc3->5';
            app.Osc35Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc35Knob.Limits = [0 10];
            app.Osc35Knob.MajorTicks = [0 10];
            app.Osc35Knob.ValueChangingFcn = createCallbackFcn(app, @Osc35KnobValueChanging, true);
            app.Osc36KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc36KnobLabel.Text = 'Osc3->6';
            app.Osc36Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc36Knob.Limits = [0 10];
            app.Osc36Knob.MajorTicks = [0 10];
            app.Osc36Knob.ValueChangingFcn = createCallbackFcn(app, @Osc36KnobValueChanging, true);
            app.Osc41KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc41KnobLabel.Text = 'Osc4->1';
            app.Osc41Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc41Knob.Limits = [0 10];
            app.Osc41Knob.MajorTicks = [0 10];
            app.Osc41Knob.ValueChangingFcn = createCallbackFcn(app, @Osc41KnobValueChanging, true);
            app.Osc42KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc42KnobLabel.Text = 'Osc4->2';
            app.Osc42Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc42Knob.Limits = [0 10];
            app.Osc42Knob.MajorTicks = [0 10];
            app.Osc42Knob.ValueChangingFcn = createCallbackFcn(app, @Osc42KnobValueChanging, true);
            app.Osc43KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc43KnobLabel.Text = 'Osc4->3';
            app.Osc43Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc43Knob.Limits = [0 10];
            app.Osc43Knob.MajorTicks = [0 10];
            app.Osc43Knob.ValueChangingFcn = createCallbackFcn(app, @Osc43KnobValueChanging, true);
            app.Osc44KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc44KnobLabel.Text = 'Osc4->4';
            app.Osc44Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc44Knob.Limits = [0 10];
            app.Osc44Knob.MajorTicks = [0 10];
            app.Osc44Knob.ValueChangingFcn = createCallbackFcn(app, @Osc44KnobValueChanging, true);
            app.Osc45KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc45KnobLabel.Text = 'Osc4->5';
            app.Osc45Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc45Knob.Limits = [0 10];
            app.Osc45Knob.MajorTicks = [0 10];
            app.Osc45Knob.ValueChangingFcn = createCallbackFcn(app, @Osc45KnobValueChanging, true);
            app.Osc46KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc46KnobLabel.Text = 'Osc4->6';
            app.Osc46Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc46Knob.Limits = [0 10];
            app.Osc46Knob.MajorTicks = [0 10];
            app.Osc46Knob.ValueChangingFcn = createCallbackFcn(app, @Osc46KnobValueChanging, true);
            app.Osc51KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc51KnobLabel.Text = 'Osc5->1';
            app.Osc51Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc51Knob.Limits = [0 10];
            app.Osc51Knob.MajorTicks = [0 10];
            app.Osc51Knob.ValueChangingFcn = createCallbackFcn(app, @Osc51KnobValueChanging, true);
            app.Osc52KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc52KnobLabel.Text = 'Osc5->2';
            app.Osc52Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc52Knob.Limits = [0 10];
            app.Osc52Knob.MajorTicks = [0 10];
            app.Osc52Knob.ValueChangingFcn = createCallbackFcn(app, @Osc52KnobValueChanging, true);
            app.Osc53KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc53KnobLabel.Text = 'Osc5->3';
            app.Osc53Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc53Knob.Limits = [0 10];
            app.Osc53Knob.MajorTicks = [0 10];
            app.Osc53Knob.ValueChangingFcn = createCallbackFcn(app, @Osc53KnobValueChanging, true);
            app.Osc54KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc54KnobLabel.Text = 'Osc5->4';
            app.Osc54Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc54Knob.Limits = [0 10];
            app.Osc54Knob.MajorTicks = [0 10];
            app.Osc54Knob.ValueChangingFcn = createCallbackFcn(app, @Osc54KnobValueChanging, true);
            app.Osc55KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc55KnobLabel.Text = 'Osc5->5';
            app.Osc55Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc55Knob.Limits = [0 10];
            app.Osc55Knob.MajorTicks = [0 10];
            app.Osc55Knob.ValueChangingFcn = createCallbackFcn(app, @Osc55KnobValueChanging, true);
            app.Osc56KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc56KnobLabel.Text = 'Osc5->6';
            app.Osc56Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc56Knob.Limits = [0 10];
            app.Osc56Knob.MajorTicks = [0 10];
            app.Osc56Knob.ValueChangingFcn = createCallbackFcn(app, @Osc56KnobValueChanging, true);
            app.Osc61KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc61KnobLabel.Text = 'Osc6->1';
            app.Osc61Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc61Knob.Limits = [0 10];
            app.Osc61Knob.MajorTicks = [0 10];
            app.Osc61Knob.ValueChangingFcn = createCallbackFcn(app, @Osc61KnobValueChanging, true);
            app.Osc62KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc62KnobLabel.Text = 'Osc6->2';
            app.Osc62Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc62Knob.Limits = [0 10];
            app.Osc62Knob.MajorTicks = [0 10];
            app.Osc62Knob.ValueChangingFcn = createCallbackFcn(app, @Osc62KnobValueChanging, true);
            app.Osc63KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc63KnobLabel.Text = 'Osc6->3';
            app.Osc63Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc63Knob.Limits = [0 10];
            app.Osc63Knob.MajorTicks = [0 10];
            app.Osc63Knob.ValueChangingFcn = createCallbackFcn(app, @Osc63KnobValueChanging, true);
            app.Osc64KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc64KnobLabel.Text = 'Osc6->4';
            app.Osc64Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc64Knob.Limits = [0 10];
            app.Osc64Knob.MajorTicks = [0 10];
            app.Osc64Knob.ValueChangingFcn = createCallbackFcn(app, @Osc64KnobValueChanging, true);
            app.Osc65KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc65KnobLabel.Text = 'Osc6->5';
            app.Osc65Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc65Knob.Limits = [0 10];
            app.Osc65Knob.MajorTicks = [0 10];
            app.Osc65Knob.ValueChangingFcn = createCallbackFcn(app, @Osc65KnobValueChanging, true);
            app.Osc66KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc66KnobLabel.Text = 'Osc6->6';
            app.Osc66Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc66Knob.Limits = [0 10];
            app.Osc66Knob.MajorTicks = [0 10];
            app.Osc66Knob.ValueChangingFcn = createCallbackFcn(app, @Osc66KnobValueChanging, true);
            app.TimeControlsTab = uitab(app.TabGroup);
            app.TimeControlsTab.Title = 'Time Controls';
            app.TimeControlsTab.Units = 'pixels';
            app.LFOAPanel = uipanel(app.TimeControlsTab);
            app.LFOAPanel.Title = 'LFO A';
            app.LfoAAxes = uiaxes(app.LFOAPanel);
            title(app.LfoAAxes, 'LFO A Plot');
            xlabel(app.LfoAAxes, 'Time');
            ylabel(app.LfoAAxes, 'Amplitude');
            app.RateHzKnob_3Label = uilabel(app.LFOAPanel);
            app.RateHzKnob_3Label.Text = 'Rate (Hz)';
            app.LfoARateKnob = uiknob(app.LFOAPanel, 'continuous');
            app.LfoARateKnob.Limits = [0 20];
            app.LfoARateKnob.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.LfoARateKnob.ValueChangingFcn = createCallbackFcn(app, @LfoARateKnobValueChanging, true);
            app.AmpKnob_3Label = uilabel(app.LFOAPanel);
            app.AmpKnob_3Label.Text = 'Amp';
            app.LfoAAmpKnob = uiknob(app.LFOAPanel, 'continuous');
            app.LfoAAmpKnob.Limits = [0 1];
            app.LfoAAmpKnob.MajorTicks = [0 0.5 1];
            app.LfoAAmpKnob.ValueChangingFcn = createCallbackFcn(app, @LfoAAmpKnobValueChanging, true);
            app.PhaseSpreadKnob_2Label = uilabel(app.LFOAPanel);
            app.PhaseSpreadKnob_2Label.Text = 'Phase Spread';
            app.LfoAPhaseSpreadKnob = uiknob(app.LFOAPanel, 'continuous');
            app.LfoAPhaseSpreadKnob.Limits = [0 1];
            app.LfoAPhaseSpreadKnob.MajorTicks = [0 0.5 1];
            app.LfoAPhaseSpreadKnob.ValueChangingFcn = createCallbackFcn(app, @LfoAPhaseSpreadKnobValueChanging, true);
            app.LFOBPanel = uipanel(app.TimeControlsTab);
            app.LFOBPanel.Title = 'LFO B';
            app.LfoBAxes = uiaxes(app.LFOBPanel);
            title(app.LfoBAxes, 'LFO B Plot');
            xlabel(app.LfoBAxes, 'Time');
            ylabel(app.LfoBAxes, 'Amplitude');
            app.RateHzKnob_4Label = uilabel(app.LFOBPanel);
            app.RateHzKnob_4Label.Text = 'Rate (Hz)';
            app.LfoBRateKnob = uiknob(app.LFOBPanel, 'continuous');
            app.LfoBRateKnob.Limits = [0 20];
            app.LfoBRateKnob.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.LfoBRateKnob.ValueChangingFcn = createCallbackFcn(app, @LfoBRateKnobValueChanging, true);
            app.AmpKnob_4Label = uilabel(app.LFOBPanel);
            app.AmpKnob_4Label.Text = 'Amp';
            app.LfoBAmpKnob = uiknob(app.LFOBPanel, 'continuous');
            app.LfoBAmpKnob.Limits = [0 1];
            app.LfoBAmpKnob.MajorTicks = [0 0.5 1];
            app.LfoBAmpKnob.ValueChangingFcn = createCallbackFcn(app, @LfoBAmpKnobValueChanging, true);
            app.PulseWidthKnob_2Label = uilabel(app.LFOBPanel);
            app.PulseWidthKnob_2Label.Text = 'Pulse Width';
            app.LfoBPulseWidthKnob = uiknob(app.LFOBPanel, 'continuous');
            app.LfoBPulseWidthKnob.Limits = [0 1];
            app.LfoBPulseWidthKnob.MajorTicks = [0 0.5 1];
            app.LfoBPulseWidthKnob.ValueChangingFcn = createCallbackFcn(app, @LfoBPulseWidthKnobValueChanging, true);
            app.EnvelopesPanel = uipanel(app.TimeControlsTab);
            app.EnvelopesPanel.Title = 'Envelopes';
            app.EnvelopesAxes = uiaxes(app.EnvelopesPanel);
            title(app.EnvelopesAxes, 'Envelopes Plot');
            xlabel(app.EnvelopesAxes, 'Time');
            ylabel(app.EnvelopesAxes, 'Amplitude');
            app.ModulationEnvelopePanel = uipanel(app.EnvelopesPanel);
            app.ModulationEnvelopePanel.Title = 'Modulation Envelope';
            app.ASlider_3Label = uilabel(app.ModulationEnvelopePanel);
            app.ASlider_3Label.Text = 'A';
            app.ModASlider = uislider(app.ModulationEnvelopePanel);
            app.ModASlider.Limits = [0 5];
            app.ModASlider.MajorTickLabels = {'', '', '', '', '', ''};
            app.ModASlider.ValueChangingFcn = createCallbackFcn(app, @ModASliderValueChanging, true);
            app.DSlider_3Label = uilabel(app.ModulationEnvelopePanel);
            app.DSlider_3Label.Text = 'D';
            app.ModDSlider = uislider(app.ModulationEnvelopePanel);
            app.ModDSlider.Limits = [0 5];
            app.ModDSlider.MajorTickLabels = {'', '', '', '', '', ''};
            app.ModDSlider.ValueChangingFcn = createCallbackFcn(app, @ModDSliderValueChanging, true);
            app.SSlider_3Label = uilabel(app.ModulationEnvelopePanel);
            app.SSlider_3Label.Text = 'S';
            app.ModSSlider = uislider(app.ModulationEnvelopePanel);
            app.ModSSlider.Limits = [0 1];
            app.ModSSlider.MajorTickLabels = {'', '', '', '', ''};
            app.ModSSlider.ValueChangingFcn = createCallbackFcn(app, @ModSSliderValueChanging, true);
            app.RSlider_3Label = uilabel(app.ModulationEnvelopePanel);
            app.RSlider_3Label.Text = 'R';
            app.ModRSlider = uislider(app.ModulationEnvelopePanel);
            app.ModRSlider.Limits = [0 5];
            app.ModRSlider.MajorTickLabels = {'', '', '', '', '', ''};
            app.ModRSlider.ValueChangingFcn = createCallbackFcn(app, @ModRSliderValueChanging, true);
            app.CurveKnob_3Label = uilabel(app.ModulationEnvelopePanel);
            app.CurveKnob_3Label.Text = 'Curve';
            app.ModCurveKnob = uiknob(app.ModulationEnvelopePanel, 'continuous');
            app.ModCurveKnob.Limits = [-5 5];
            app.ModCurveKnob.MajorTicks = [-5 0 5];
            app.ModCurveKnob.ValueChangingFcn = createCallbackFcn(app, @ModCurveKnobValueChanging, true);
            app.AmplitudeEnvelopePanel = uipanel(app.EnvelopesPanel);
            app.AmplitudeEnvelopePanel.Title = 'Amplitude Envelope';
            app.ASlider_4Label = uilabel(app.AmplitudeEnvelopePanel);
            app.ASlider_4Label.Text = 'A';
            app.AmpASlider = uislider(app.AmplitudeEnvelopePanel);
            app.AmpASlider.Limits = [0 5];
            app.AmpASlider.MajorTickLabels = {'', '', '', '', '', ''};
            app.AmpASlider.ValueChangingFcn = createCallbackFcn(app, @AmpASliderValueChanging, true);
            app.DSlider_4Label = uilabel(app.AmplitudeEnvelopePanel);
            app.DSlider_4Label.Text = 'D';
            app.AmpDSlider = uislider(app.AmplitudeEnvelopePanel);
            app.AmpDSlider.Limits = [0 5];
            app.AmpDSlider.MajorTickLabels = {'', '', '', '', '', ''};
            app.AmpDSlider.ValueChangingFcn = createCallbackFcn(app, @AmpDSliderValueChanging, true);
            app.SSlider_4Label = uilabel(app.AmplitudeEnvelopePanel);
            app.SSlider_4Label.Text = 'S';
            app.AmpSSlider = uislider(app.AmplitudeEnvelopePanel);
            app.AmpSSlider.Limits = [0 1];
            app.AmpSSlider.MajorTickLabels = {'', '', '', '', ''};
            app.AmpSSlider.ValueChangingFcn = createCallbackFcn(app, @AmpSSliderValueChanging, true);
            app.RSlider_4Label = uilabel(app.AmplitudeEnvelopePanel);
            app.RSlider_4Label.Text = 'R';
            app.AmpRSlider = uislider(app.AmplitudeEnvelopePanel);
            app.AmpRSlider.Limits = [0 5];
            app.AmpRSlider.MajorTickLabels = {'', '', '', '', '', ''};
            app.AmpRSlider.ValueChangingFcn = createCallbackFcn(app, @AmpRSliderValueChanging, true);
            app.CurveKnob_4Label = uilabel(app.AmplitudeEnvelopePanel);
            app.CurveKnob_4Label.Text = 'Curve';
            app.AmpCurveKnob = uiknob(app.AmplitudeEnvelopePanel, 'continuous');
            app.AmpCurveKnob.Limits = [-5 5];
            app.AmpCurveKnob.MajorTicks = [-5 0 5];
            app.AmpCurveKnob.ValueChangingFcn = createCallbackFcn(app, @AmpCurveKnobValueChanging, true);
            app.VibratoPanel = uipanel(app.TimeControlsTab);
            app.VibratoPanel.Title = 'Vibrato';
            app.StartRateHzKnob_2Label = uilabel(app.VibratoPanel);
            app.StartRateHzKnob_2Label.Text = 'Start Rate (Hz)';
            app.VibStartRateKnob = uiknob(app.VibratoPanel, 'continuous');
            app.VibStartRateKnob.Limits = [0 20];
            app.VibStartRateKnob.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.VibStartRateKnob.ValueChangingFcn = createCallbackFcn(app, @VibStartRateKnobValueChanging, true);
            app.EndRateHzKnob_2Label = uilabel(app.VibratoPanel);
            app.EndRateHzKnob_2Label.Text = 'End Rate (Hz)';
            app.VibEndRateKnob = uiknob(app.VibratoPanel, 'continuous');
            app.VibEndRateKnob.Limits = [0 20];
            app.VibEndRateKnob.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.VibEndRateKnob.ValueChangingFcn = createCallbackFcn(app, @VibEndRateKnobValueChanging, true);
            app.StartAmtKnob_2Label = uilabel(app.VibratoPanel);
            app.StartAmtKnob_2Label.Text = 'Start Amt';
            app.VibStartAmtKnob = uiknob(app.VibratoPanel, 'continuous');
            app.VibStartAmtKnob.Limits = [0 10];
            app.VibStartAmtKnob.ValueChangingFcn = createCallbackFcn(app, @VibStartAmtKnobValueChanging, true);
            app.EndAmtKnob_2Label = uilabel(app.VibratoPanel);
            app.EndAmtKnob_2Label.Text = 'End Amt';
            app.VibEndAmtKnob = uiknob(app.VibratoPanel, 'continuous');
            app.VibEndAmtKnob.Limits = [0 10];
            app.VibEndAmtKnob.ValueChangingFcn = createCallbackFcn(app, @VibEndAmtKnobValueChanging, true);
            app.EnvTimesKnob_2Label = uilabel(app.VibratoPanel);
            app.EnvTimesKnob_2Label.Text = 'Env Time (s)';
            app.VibEnvTimeKnob = uiknob(app.VibratoPanel, 'continuous');
            app.VibEnvTimeKnob.Limits = [0 20];
            app.VibEnvTimeKnob.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.VibEnvTimeKnob.ValueChangingFcn = createCallbackFcn(app, @VibEnvTimeKnobValueChanging, true);
            app.GlobalModifiersPanel = uipanel(app.TimeControlsTab);
            app.GlobalModifiersPanel.Title = 'Global Modifiers';
            app.StereoSpreadKnob_2Label = uilabel(app.GlobalModifiersPanel);
            app.StereoSpreadKnob_2Label.Text = 'Stereo Spread';
            app.StereoSpreadKnob = uiknob(app.GlobalModifiersPanel, 'continuous');
            app.StereoSpreadKnob.Limits = [0 1];
            app.StereoSpreadKnob.MajorTicks = [0 0.5 1];
            app.StereoSpreadKnob.ValueChangingFcn = createCallbackFcn(app, @StereoSpreadKnobValueChanging, true);
            app.LoadSelectedPresetButton = uibutton(app.UIFigure, 'push');
            app.LoadSelectedPresetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSelectedPresetButtonPushed, true);
            app.LoadSelectedPresetButton.Text = 'Load Selected Preset';
            app.PresetSpinnerLabel = uilabel(app.UIFigure);
            app.PresetSpinnerLabel.Text = 'Preset';
            app.PresetSpinner = uispinner(app.UIFigure);
            app.PresetSpinner.ValueChangedFcn = createCallbackFcn(app, @PresetSpinnerValueChanged, true);
            app.PresetSpinner.Limits = [1 Inf];
            app.PresetSpinner.RoundFractionalValues = 'on';
            app.PresetSpinner.Value = 1;
            app.ReWriteSelectedPresetButton = uibutton(app.UIFigure, 'push');
            app.ReWriteSelectedPresetButton.ButtonPushedFcn = createCallbackFcn(app, @ReWriteSelectedPresetButtonPushed, true);
            app.ReWriteSelectedPresetButton.Text = 'Re-Write Selected Preset';
            app.SaveasNewPresetButton = uibutton(app.UIFigure, 'push');
            app.SaveasNewPresetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasNewPresetButtonPushed, true);
            app.SaveasNewPresetButton.Text = 'Save as New Preset';
            app.ReturntoSelectionUIButton = uibutton(app.UIFigure, 'push');
            app.ReturntoSelectionUIButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoSelectionUIButtonPushed, true);
            app.ReturntoSelectionUIButton.Text = 'Return to Selection UI';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SynthUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end